classdef Serial_AppDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SerialPortNumberDropDownLabel  matlab.ui.control.Label
        serialListDropDown             matlab.ui.control.DropDown
        connectButton                  matlab.ui.control.Button
        ReceiveDatafromSerialPanel     matlab.ui.container.Panel
        receiveTextBox                 matlab.ui.control.EditField
        SendDatafromSerialPanel        matlab.ui.container.Panel
        sendTextBox                    matlab.ui.control.EditField
        sendButton                     matlab.ui.control.Button
        disconnectButton               matlab.ui.control.Button
        GaugeLabel                     matlab.ui.control.Label
        Gauge                          matlab.ui.control.LinearGauge
    end

    
    methods (Access = private)
        
        function results = receiveSerial(app)
             % uialert(app.UIFigure,"receiveSerial function called.","Debug","Icon","info");
             
             global isConnected;
             global s;
             
             while isConnected
                 try
                     received = fgets(s);       % Fetch data every certain interval.
                     received = received(1:end-2);  % Remove /r/n from Serial.println()
                     
                     receivedDelimeted = strsplit(received,",");
                     
                     app.receiveTextBox.Value = convertCharsToStrings(received);
                      
                     app.Gauge.Value = str2double(receivedDelimeted(2));
                     
                     
                     drawnow;   % Update GUI objects immediately.
                     
                     results = true;
                 catch ME
                     uialert(app.UIFigure, ME.message,"Error", "Icon","error");
                      
                     UIFigureCloseRequest(app);
                 end
             end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global isConnected;
            global serialList;
                   
            isConnected = false;        % Variable to control if serial is connected or not.
            serialList = seriallist;    % Variable for the list of serial.
                
            % Check if the computer detects a serial port
            % and add it in the serial list drop down.
            if ~isequal(size(seriallist),0)
               app.serialListDropDown.Items=serialList;
            end
            
            app.disconnectButton.Enable = false;
        end

        % Button pushed function: connectButton
        function connectButtonPushed(app, event)
           global isConnected;
           global s;
           
           if ~isConnected
               % uialert(app.UIFigure,"Connecting... Press OK to proceed.","Debug","Icon","info");
               
               % Check if there is atleast one serial port
               % in the serial drop down.
               
               serialName = convertCharsToStrings(app.serialListDropDown.Value);
               
               if serialName ~= ""
                   
                   try
                       s = serial(serialName);
                       fopen(s);
                      
                       
                       isConnected = true;
                       app.connectButton.Enable = false;
                       app.disconnectButton.Enable = true;
                       
                   catch ME
                       uialert(app.UIFigure, ME.message,"Error", "Icon","error");
                       uialert(app.UIFigure, "Cannot connect to serial object. Disconnect the device and restart whole MATLAB Application.","Error", "Icon","error");
                   end
                   
                   receiveSerial(app);
                   
               else
                   
                   uialert(app.UIFigure, "No ports selected!","Error", "Icon","error");
               end             
           end
            
        end

        % Button pushed function: sendButton
        function sendButtonPushed(app, event)
           global isConnected;
           global s;
           
           if isConnected
               toSend = convertCharsToStrings(app.sendTextBox.Value)+newline;
           
               fwrite(s,toSend);
           else
               uialert(app.UIFigure, "Your not connected to any port.","Error", "Icon","error");
           end
        end

        % Button pushed function: disconnectButton
        function disconnectButtonPushed(app, event)
            global isConnected;
            global s;
            
            if isConnected
                
                fclose(s);
                
                isConnected = false;
                app.connectButton.Enable = true;
                app.disconnectButton.Enable = false;
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            disconnectButtonPushed(app);
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SerialPortNumberDropDownLabel = uilabel(app.UIFigure);
            app.SerialPortNumberDropDownLabel.Text = 'Serial Port Number:';
            app.serialListDropDown = uidropdown(app.UIFigure);
            app.serialListDropDown.Items = {};
            app.serialListDropDown.Value = {};
            app.connectButton = uibutton(app.UIFigure, 'push');
            app.connectButton.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.connectButton.Text = {'Connect'; ''};
            app.ReceiveDatafromSerialPanel = uipanel(app.UIFigure);
            app.ReceiveDatafromSerialPanel.Title = 'Receive Data from Serial';
            app.receiveTextBox = uieditfield(app.ReceiveDatafromSerialPanel, 'text');
            app.SendDatafromSerialPanel = uipanel(app.UIFigure);
            app.SendDatafromSerialPanel.Title = 'Send Data from Serial';
            app.sendTextBox = uieditfield(app.SendDatafromSerialPanel, 'text');
            app.sendButton = uibutton(app.SendDatafromSerialPanel, 'push');
            app.sendButton.ButtonPushedFcn = createCallbackFcn(app, @sendButtonPushed, true);
            app.sendButton.Text = {'Send'; ''};
            app.disconnectButton = uibutton(app.UIFigure, 'push');
            app.disconnectButton.ButtonPushedFcn = createCallbackFcn(app, @disconnectButtonPushed, true);
            app.disconnectButton.Text = 'Disconnect';
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'linear');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Serial_AppDesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end